classdef Fuzzy_EQ_Ver_01 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        LowKnobLabel                 matlab.ui.control.Label
        LowKnob                      matlab.ui.control.Knob
        MidKnob                      matlab.ui.control.Knob
        HighKnob                     matlab.ui.control.Knob
        MidLabel                     matlab.ui.control.Label
        HighLabel                    matlab.ui.control.Label
        BPMEditFieldLabel            matlab.ui.control.Label
        BPMEditField                 matlab.ui.control.NumericEditField
        InstrumentDropDownLabel      matlab.ui.control.Label
        InstrumentDropDown           matlab.ui.control.DropDown
        ExportButton                 matlab.ui.control.Button
        BrowseButton                 matlab.ui.control.Button
        FuzzyEQV01Label              matlab.ui.control.Label
        TimeSignatureEditFieldLabel  matlab.ui.control.Label
        TimeSignatureEditField       matlab.ui.control.NumericEditField
        Label                        matlab.ui.control.Label
    end


    properties (Access = private)
    end


    methods (Access = private)

        % Value changed function: LowKnob
        function LowKnobValueChanged(app, event)
           global User_Low
            User_Low = app.LowKnob.Value;
            
        end

        % Value changed function: MidKnob
        function MidKnobValueChanged(app, event)
           global User_Mid
            User_Mid = app.MidKnob.Value;
            
        end

        % Value changed function: HighKnob
        function HighKnobValueChanged(app, event)
           global User_High
            User_High = app.HighKnob.Value;
            
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
           global x
           global Fs
            [file,path] = uigetfile({'*.mp3';'*.wav';'*.*'},...
                          'File Selector');
            [x, Fs] = audioread(fullfile(path, file));
        end

        % Value changed function: BPMEditField
        function BPMEditFieldValueChanged(app, event)
            global BPM
            BPM = app.BPMEditField.Value;
            
        end

        % Value changed function: InstrumentDropDown
        function InstrumentDropDownValueChanged(app, event)
           global Instrument
            Instrument = app.InstrumentDropDown.Value;
            
        end

        % Value changed function: TimeSignatureEditField
        function TimeSignatureEditFieldValueChanged(app, event)
           global  timesig
           timesig = app.TimeSignatureEditField.Value;
            
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
                        global User_Low
            global User_Mid
            global User_High
            global file
            global BPM
            global Instrument
            global timesig
            global x
            global Fs
            bps=bpm/60;
            barsec=(1/bps)*timesig;
            i=1;
            j=1;
            bar{i}=x(1:(Fs*barsec)-1);
            i=i+1;
            while Fs*(barsec*(i+1))<=length(x)
                bar{i}=x(Fs*(barsec*j):(Fs*(barsec*(j+1))-1));
                i=i+1;
                j=j+1;
            end
            j=j+1;
            bar{i}=x(Fs*(barsec*j):length(x));
            bar=bar';
            %**************************
            %**********FFT Part********
            T=1/Fs;
            for k=1:length(bar)
                L(k)=length(bar{k});
            end
            %**************************
            for k=1:length(bar)
                y{k}=fft(bar{k});
                P2{k} = (abs(y{k}/L(k)));
                P1{k} = P2{k}(1:L(k)/2+1);
                P1{k}(2:end-1) = 2*P1{k}(2:end-1);
                f{k} = Fs*(0:(L(k)/2))/L(k);
            end
            for n=1:length(P1{2})
                some=0;   %???position
                for m=2:length(P1)-1
                    some=P1{m}(n)+some;
                end
                aver(n)=some/(length(P1)-2);
            end
            max_aver=max(aver);
            aver_nor=aver/max_aver;
            
            if Instrument=='Bass'
                Low=aver_nor(40:80);
                Low_range=[40 80];
                Mid=aver_nor(81:320);
                Mid_range=[81 320];
                High=aver_nor(321:5000);
                High_range=[321 5000];
                
                aver_Low=sum(Low)/length(Low);
                aver_Mid=sum(Mid)/length(Mid);
                aver_High=sum(High)/length(High);
                
            else if Instrument=='Guitar'
                    Low=aver_nor(80:250);
                    Low_range=[80 250];
                    Mid=aver_nor(251:1250);
                    Mid_range=[251 1250];
                    High=aver_nor(1251:5000);
                    High_range=[1251 5000];
 
                    aver_Low=sum(Low)/length(Low);
                    aver_Mid=sum(Mid)/length(Mid);
                    aver_High=sum(High)/length(High);    
                
            else if Instrument=='Snare'
                    Low=aver_nor(120:240);
                    Low_range=[120 240];
                    Mid=aver_nor(241:2500);
                    Mid_range=[241 2500];
                    High=aver_nor(2501:5000);
                    High_range=[2501 5000];
 
                    aver_Low=sum(Low)/length(Low);
                    aver_Mid=sum(Mid)/length(Mid);
                    aver_High=sum(High)/length(High);
                    
            else if Instrument=='Kick'
                    Low=aver_nor(50:80);
                    Low_range=[50 80];
                    Mid=aver_nor(81:500);
                    Mid_range=[81 500];
                    High=aver_nor(501:6300);
                    High_range=[501 6300];
 
                    aver_Low=sum(Low)/length(Low);
                    aver_Mid=sum(Mid)/length(Mid);
                    aver_High=sum(High)/length(High);
                    
            else if Instrument=='Piano'
                    Low=aver_nor(27:250);
                    Low_range=[27 250];
                    Mid=aver_nor(251:2000);
                    Mid_range=[251 2000];
                    High=aver_nor(2001:10000);
                    High_range=[2001 10000];
 
                    aver_Low=sum(Low)/length(Low);
                    aver_Mid=sum(Mid)/length(Mid);
                    aver_Highs=sum(High)/length(High);
                    
            else if Instrument=='Male Vocal'
                    Low=aver_nor(120:900);
                    Low_range=[120 900];
                    Mid=aver_nor(901:8000);
                    Mid_range=[901 8000];
                    High=aver_nor(8001:16000);
                    High_range=[8001 16000];
 
                    aver_Low=sum(Low)/length(Low);
                    aver_Mid=sum(Mid)/length(Mid);
                    aver_High=sum(High)/length(High);
                    
            else if Instrument=='Female Vocal'
                    Low=aver_nor(240:1200);
                    Low_range=[240 1200];
                    Mid=aver_nor(1201:8000);
                    Mid_range=[1201 8000];
                    High=aver_nor(8001:16000);
                    High_range=[8001 16000];
 
                    aver_Low=sum(Low)/length(Low);
                    aver_Mid=sum(Mid)/length(Mid);
                    aver_High=sum(High)/length(High);
                           
            end
            
            [val_max,max_idx]=max(aver_nor);
            low_idk=0;
            if  Mid_range(1)<=max_idx<=Mid_range(2)
                low_lim=max_idx;
                m=1;
                while low_lim/2>=Low_range(1)
                    low_idk(m)=low_lim/2;
                    low_lim=low_lim/2;
                    m=m+1;
                end
                low_idk=flip(low_idk);
            
                high_lim=max_idx;
                m=1;
                while high_lim*2<=High_range(2)
                    high_idk(m)=high_lim*2;
                    high_lim=high_lim*2;
                    m=m+1;
                end
                cen_idk=[low_idk high_idk];
                low_cen=min(cen_idk);
                mid_cen=max_idx;
                high_cen=max(cen_idk);
            end
            
            if max_idx<Low_range(2)
                mid_lim=max_idx;
                m=1;
                while mid_lim*2<=Mid_range(2)
                    mid_idk(m)=mid_lim*2;
                    mid_lim=mid_lim*2;
                    m=m+1;
                end
                 high_lim=mid_lim;
                 m=1;
                while high_lim*2<=High_range(2)
                    high_idk(m)=high_lim*2;
                    high_lim=high_lim*2;
                    m=m+1;
                end
                low_cen=max_idx;
                mid_cen=max(mid_idk);
                high_cen=max(high_idk);
            end
            
            %Input Part***************************************
            
            User_G_B1=-5;
            User_G_B2=7;
            User_G_B3=3;
            
            %Fuzzy Part***************************************
            
            Fuzzy_Gain = readfis('Fuzzy_Gain_Ver1.fis');
            %type Fuzzy_Gain_Ver1.fis;
            evalfis([User_G_B1 User_G_B2 User_G_B3 aver_Low aver_Mid aver_High], Fuzzy_Gain);
            Gain=ans;
            clear('ans');
            Fuzzy_Q = readfis('Fuzzy_Q_Ver1.fis');
            evalfis([Gain(1) Gain(2) Gain(3)], Fuzzy_Q);
            Q_fuzz=ans;
            clear('ans');
            
            %EQ Part**********************
            %*********Fix Variable********
            fs=41000;
            fc=[low_cen mid_cen high_cen];
            G=[Gain(1) Gain(2) Gain(3)];
            Q=[Q_fuzz(1) Q_fuzz(2) Q_fuzz(3)];
            n=44100;
            wc = 2*pi*fc/fs;
            %band1
            A(1) = sqrt(10^(G(1)/20));
            wc(1) = 2*pi*fc(1)/fs;
            wS(1) = sin(wc(1));
            wC(1) = cos(wc(1));
            alpha(1) = wS(1)/(2*Q(1));
             
            b0(1) = 1+(alpha(1) * A(1));
            b1(1) = (-2)*wC(1);
            b2(1) = 1-(alpha(1)*A(1));
            a0(1) = 1+(alpha(1)/A(1));
            a1(1) = (-2)*(wC(1));
            a2(1) = 1-(alpha(1)/A(1));
            
            Hu{1} = [(b0(1)/a0(1)) (b1(1)/a0(1)) (b2(1)/a0(1))];
            Hl{1} = [1 (a1(1)/a0(1)) a2(1)/a0(1)];
            
            %band2
            A(2) = sqrt(10^(G(2)/20));
            wc(2) = 2*pi*fc(2)/fs;
            wS(2) = sin(wc(2));
            wC(2) = cos(wc(2));
            alpha(2) = wS(2)/(2*Q(2));
            
            b0(2) = 1+(alpha(2) * A(2));
            b1(2) = (-2)*wC(2);
            b2(2) = 1-(alpha(2)*A(2));
            a0(2) = 1+(alpha(2)/A(2));
            a1(2) = (-2)*(wC(2));
            a2(2) = 1-(alpha(2)/A(2))
            
            Hu{2} = [(b0(2)/a0(2)) (b1(2)/a0(2)) (b2(2)/a0(2))];
            Hl{2} = [1 (a1(2)/a0(2)) a2(2)/a0(2)];
            
            %band3
            A(3) = sqrt(10^(G(3)/20));
            wc(3) = 2*pi*fc(3)/fs;
            wS(3) = sin(wc(3));
            wC(3) = cos(wc(3));
            alpha(3) = wS(3)/(2*Q(3));

            b0(3) = 1+(alpha(3) * A(3));
            b1(3) = (-2)*wC(3);
            b2(3) = 1-(alpha(3)*A(3));
            a0(3) = 1+(alpha(3)/A(3));
            a1(3) = (-2)*(wC(3));
            a2(3) = 1-(alpha(3)/A(3))
            
            Hu{3} = [(b0(3)/a0(3)) (b1(3)/a0(3)) (b2(3)/a0(3))];
            Hl{3} = [1 (a1(3)/a0(3)) a2(3)/a0(3)];
             
            band{1}=dfilt.df1(Hu{1},Hl{1});
            band{2}=dfilt.df1(Hu{2},Hl{2});
            band{3}=dfilt.df1(Hu{3},Hl{3});
            allband = dfilt.cascade(band{1},band{2},band{3});
            h=fvtool(allband);
            h.Fs = fs;
            h.FrequencyRange='[0, fs/2)';
            y=filter(allband,x);

            audiowrite('Save Audio.wav',y,fs)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LowKnobLabel = uilabel(app.UIFigure);
            app.LowKnobLabel.Text = 'Low';
            app.LowKnob = uiknob(app.UIFigure, 'continuous');
            app.LowKnob.Limits = [-10 10];
            app.LowKnob.ValueChangedFcn = createCallbackFcn(app, @LowKnobValueChanged, true);
            app.MidKnob = uiknob(app.UIFigure, 'continuous');
            app.MidKnob.Limits = [-10 10];
            app.MidKnob.ValueChangedFcn = createCallbackFcn(app, @MidKnobValueChanged, true);
            app.HighKnob = uiknob(app.UIFigure, 'continuous');
            app.HighKnob.Limits = [-10 10];
            app.HighKnob.ValueChangedFcn = createCallbackFcn(app, @HighKnobValueChanged, true);
            app.MidLabel = uilabel(app.UIFigure);
            app.MidLabel.Text = 'Mid';
            app.HighLabel = uilabel(app.UIFigure);
            app.HighLabel.Text = 'High';
            app.BPMEditFieldLabel = uilabel(app.UIFigure);
            app.BPMEditFieldLabel.Text = 'BPM';
            app.BPMEditField = uieditfield(app.UIFigure, 'numeric');
            app.BPMEditField.ValueChangedFcn = createCallbackFcn(app, @BPMEditFieldValueChanged, true);
            app.InstrumentDropDownLabel = uilabel(app.UIFigure);
            app.InstrumentDropDownLabel.Text = 'Instrument';
            app.InstrumentDropDown = uidropdown(app.UIFigure);
            app.InstrumentDropDown.Items = {'Bass', 'Guitar', 'Snare', 'Kick', 'Piano', 'Male Vocal', 'Female Vocal'};
            app.InstrumentDropDown.ValueChangedFcn = createCallbackFcn(app, @InstrumentDropDownValueChanged, true);
            app.InstrumentDropDown.Value = 'Bass';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.FuzzyEQV01Label = uilabel(app.UIFigure);
            app.FuzzyEQV01Label.Text = 'Fuzzy EQ V.0.1';
            app.TimeSignatureEditFieldLabel = uilabel(app.UIFigure);
            app.TimeSignatureEditFieldLabel.Text = 'Time Signature';
            app.TimeSignatureEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeSignatureEditField.ValueChangedFcn = createCallbackFcn(app, @TimeSignatureEditFieldValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '/4';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Fuzzy_EQ_Ver_01

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end